classdef softVotingDemoApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SoftVotingDemoUIFigure          matlab.ui.Figure
        HelpMenu                        matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
        S6Axes                          matlab.ui.control.UIAxes
        S4Axes                          matlab.ui.control.UIAxes
        S2Axes                          matlab.ui.control.UIAxes
        S5Axes                          matlab.ui.control.UIAxes
        S3Axes                          matlab.ui.control.UIAxes
        S1Axes                          matlab.ui.control.UIAxes
        GridLayout2                     matlab.ui.container.GridLayout
        ResultsPanel_2                  matlab.ui.container.Panel
        STextArea                       matlab.ui.control.TextArea
        SLabel                          matlab.ui.control.Label
        M6TextArea                      matlab.ui.control.TextArea
        mu_6x_nLabel                    matlab.ui.control.Label
        M5TextArea                      matlab.ui.control.TextArea
        mu_5x_nLabel                    matlab.ui.control.Label
        M4TextArea                      matlab.ui.control.TextArea
        mu_4x_nLabel                    matlab.ui.control.Label
        M3TextArea                      matlab.ui.control.TextArea
        mu_3x_nLabel                    matlab.ui.control.Label
        M2TextArea                      matlab.ui.control.TextArea
        mu_2x_nLabel                    matlab.ui.control.Label
        M1TextArea                      matlab.ui.control.TextArea
        mu_1x_nTextAreaLabel            matlab.ui.control.Label
        MembershipfunctionsettingPanel  matlab.ui.container.Panel
        A_3Label                        matlab.ui.control.Label
        MFP3EditField                   matlab.ui.control.NumericEditField
        A_2Label                        matlab.ui.control.Label
        MFP2EditField                   matlab.ui.control.NumericEditField
        A_1Label                        matlab.ui.control.Label
        MFP1EditField                   matlab.ui.control.NumericEditField
        MembershipFunctionDropDown      matlab.ui.control.DropDown
        MembershipFunctionLabel         matlab.ui.control.Label
        SignalsvaluesPanel              matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        x_6Label                        matlab.ui.control.Label
        x_5Label                        matlab.ui.control.Label
        x_4Label                        matlab.ui.control.Label
        x_3Label                        matlab.ui.control.Label
        x_2Label                        matlab.ui.control.Label
        x_1Label                        matlab.ui.control.Label
        S6Slider                        matlab.ui.control.Slider
        S5Slider                        matlab.ui.control.Slider
        S4Slider                        matlab.ui.control.Slider
        S3Slider                        matlab.ui.control.Slider
        S2Slider                        matlab.ui.control.Slider
        S1Slider                        matlab.ui.control.Slider
        SignalssettingPanel             matlab.ui.container.Panel
        SnToEditField                   matlab.ui.control.NumericEditField
        toLabel                         matlab.ui.control.Label
        fromLabel                       matlab.ui.control.Label
        x_nLabel                        matlab.ui.control.Label
        SnFromEditField                 matlab.ui.control.NumericEditField
        QuantityDropDown                matlab.ui.control.DropDown
        QuantityLabel                   matlab.ui.control.Label
    end

    
    properties (Access = public)
        Signals % Description
        MembershipFunction
        TrustMatrix % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.SoftVotingDemoUIFigure, 'center');
            
            dev.initialize_data_stuctures(app);
            dev.enable_signals_components(app);
            dev.enable_axes(app);
            dev.enable_mf_components(app);
            dev.enable_results(app);
            dev.set_uitextarea_position(app);
            dev.calculate_membership_functions(app);
            dev.calculate_trust_matrix(app);
            dev.calculate_consolidated_signal(app);
            dev.app_plot(app);
            dev.enable_graphics(app);
            dev.display_results(app);
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            open guide.mlx;
        end

        % Value changed function: QuantityDropDown
        function QuantityDropDownValueChanged(app, event)
            app.Signals.Quantity = app.QuantityDropDown.Value;

            dev.enable_signals_components(app);
            dev.enable_axes(app);
            dev.enable_results(app);
            dev.calculate_membership_functions(app);
            dev.calculate_trust_matrix(app);
            dev.calculate_consolidated_signal(app);
            dev.app_plot(app);
            dev.enable_graphics(app);
            dev.display_results(app);
            dev.set_uitextarea_position(app);
        end

        % Value changed function: SnFromEditField
        function SnFromEditFieldValueChanged(app, event)
            if app.SnFromEditField.Value < app.SnToEditField.Value
                app.Signals.LeftLimit = app.SnFromEditField.Value;

                dev.set_signals_sliders_limits(app);
                dev.calculate_membership_functions(app);
                dev.app_plot(app)
                dev.enable_graphics(app);
            else
                dev.process_errors(app, 'Left limit is incorrect');
                app.SnFromEditField.Value = app.Signals.LeftLimit;
            end
        end

        % Value changed function: SnToEditField
        function SnToEditFieldValueChanged(app, event)
            if app.SnFromEditField.Value < app.SnToEditField.Value
                app.Signals.RightLimit = app.SnToEditField.Value;

                dev.set_signals_sliders_limits(app);
                dev.calculate_membership_functions(app);
                dev.app_plot(app);
                dev.enable_graphics(app);
            else
                dev.process_errors(app, 'Right limit is incorrect');
                app.SnToEditField.Value = app.Signals.RightLimit;
            end
        end

        % Value changed function: S1Slider
        function S1SliderValueChanged(app, event)
            app.Signals.S1.CurrentValue = app.S1Slider.Value;
            
            dev.calculate_membership_functions(app);
            dev.calculate_trust_matrix(app);
            dev.calculate_consolidated_signal(app);
            dev.app_plot(app);
            dev.enable_graphics(app);
            dev.display_results(app);
        end

        % Value changed function: S2Slider
        function S2SliderValueChanged(app, event)
            app.Signals.S2.CurrentValue = app.S2Slider.Value;
            
            dev.calculate_membership_functions(app);
            dev.calculate_trust_matrix(app);
            dev.calculate_consolidated_signal(app);
            dev.app_plot(app);
            dev.enable_graphics(app);
            dev.display_results(app);
        end

        % Value changed function: S3Slider
        function S3SliderValueChanged(app, event)
            app.Signals.S3.CurrentValue = app.S3Slider.Value;
            
            dev.calculate_membership_functions(app);
            dev.calculate_trust_matrix(app);
            dev.calculate_consolidated_signal(app);
            dev.app_plot(app);
            dev.enable_graphics(app);
            dev.display_results(app);
        end

        % Value changed function: S4Slider
        function S4SliderValueChanged(app, event)
            app.Signals.S4.CurrentValue = app.S4Slider.Value;
            
            dev.calculate_membership_functions(app);
            dev.calculate_trust_matrix(app);
            dev.calculate_consolidated_signal(app);
            dev.app_plot(app);
            dev.enable_graphics(app);
            dev.display_results(app);
        end

        % Value changed function: S5Slider
        function S5SliderValueChanged(app, event)
            app.Signals.S5.CurrentValue = app.S5Slider.Value;
            
            dev.calculate_membership_functions(app);
            dev.calculate_trust_matrix(app);
            dev.calculate_consolidated_signal(app);
            dev.app_plot(app);
            dev.enable_graphics(app);
            dev.display_results(app);
        end

        % Value changed function: S6Slider
        function S6SliderValueChanged(app, event)
            app.Signals.S6.CurrentValue = app.S6Slider.Value;
            
            dev.calculate_membership_functions(app);
            dev.calculate_trust_matrix(app);
            dev.calculate_consolidated_signal(app);
            dev.app_plot(app);
            dev.enable_graphics(app);
            dev.display_results(app);
        end

        % Value changed function: MembershipFunctionDropDown
        function MembershipFunctionDropDownValueChanged(app, event)
            app.MembershipFunction.Type = app.MembershipFunctionDropDown.Value;
            
            dev.set_initial_preferences(app);
            dev.calculate_membership_functions(app);
            dev.calculate_trust_matrix(app);
            dev.calculate_consolidated_signal(app);
            dev.app_plot(app);
            dev.enable_graphics(app);
            dev.enable_mf_components(app);
            dev.display_results(app);
        end

        % Value changed function: MFP1EditField
        function MFP1EditFieldValueChanged(app, event)
            if any(strcmp(app.MembershipFunction.Type, ...
                    {'Generalized bell-shaped', 'Triangular', 'Gaussian'}))
                app.MembershipFunction.P1 = app.MFP1EditField.Value;

                dev.calculate_membership_functions(app);
                dev.calculate_trust_matrix(app);
                dev.calculate_consolidated_signal(app);
                dev.app_plot(app);
                dev.enable_graphics(app);
                dev.display_results(app);
            else
                if app.MFP2EditField.Value < app.MFP1EditField.Value
                    app.MembershipFunction.P1 = app.MFP1EditField.Value;

                    dev.calculate_membership_functions(app);
                    dev.calculate_trust_matrix(app);
                    dev.calculate_consolidated_signal(app);
                    dev.app_plot(app);
                    dev.enable_graphics(app);
                    dev.display_results(app);
                else
                    dev.process_errors(app, 'The first parameter is incorrect');
                    app.MFP1EditField.Value = app.MembershipFunction.P1;
                end
            end
        end

        % Value changed function: MFP2EditField
        function MFP2EditFieldValueChanged(app, event)
            app.MembershipFunction.P2 = app.MFP2EditField.Value;

            dev.calculate_membership_functions(app);
            dev.calculate_trust_matrix(app);
            dev.calculate_consolidated_signal(app);
            dev.app_plot(app);
            dev.enable_graphics(app);
            dev.display_results(app);
        end

        % Value changed function: MFP3EditField
        function MFP3EditFieldValueChanged(app, event)
            app.MembershipFunction.P3 = app.MFP3EditField.Value;

            dev.calculate_membership_functions(app);
            dev.calculate_trust_matrix(app);
            dev.calculate_consolidated_signal(app);
            dev.app_plot(app);
            dev.enable_graphics(app);
            dev.display_results(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SoftVotingDemoUIFigure = uifigure('Visible', 'off');
            app.SoftVotingDemoUIFigure.Name = 'Soft Voting Demo';
            app.HelpMenu = uimenu(app.SoftVotingDemoUIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.GridLayout = uigridlayout(app.SoftVotingDemoUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SignalssettingPanel = uipanel(app.GridLayout2);
            app.SignalssettingPanel.Title = 'Signals setting';
            app.QuantityLabel = uilabel(app.SignalssettingPanel);
            app.QuantityLabel.Text = 'Quantity:';
            app.QuantityDropDown = uidropdown(app.SignalssettingPanel);
            app.QuantityDropDown.Items = {'2', '3', '4', '5', '6'};
            app.QuantityDropDown.ValueChangedFcn = createCallbackFcn(app, @QuantityDropDownValueChanged, true);
            app.QuantityDropDown.Value = '3';
            app.SnFromEditField = uieditfield(app.SignalssettingPanel, 'numeric');
            app.SnFromEditField.ValueChangedFcn = createCallbackFcn(app, @SnFromEditFieldValueChanged, true);
            app.x_nLabel = uilabel(app.SignalssettingPanel);
            app.x_nLabel.Interpreter = 'latex';
            app.x_nLabel.Text = '$x_{n}$:';
            app.fromLabel = uilabel(app.SignalssettingPanel);
            app.fromLabel.Text = 'from';
            app.toLabel = uilabel(app.SignalssettingPanel);
            app.toLabel.Text = 'to';
            app.SnToEditField = uieditfield(app.SignalssettingPanel, 'numeric');
            app.SnToEditField.ValueChangedFcn = createCallbackFcn(app, @SnToEditFieldValueChanged, true);
            app.SnToEditField.Value = 10;
            app.SignalsvaluesPanel = uipanel(app.GridLayout2);
            app.SignalsvaluesPanel.Title = 'Signals values';
            app.GridLayout4 = uigridlayout(app.SignalsvaluesPanel);
            app.S1Slider = uislider(app.GridLayout4);
            app.S1Slider.Limits = [0 10];
            app.S1Slider.ValueChangedFcn = createCallbackFcn(app, @S1SliderValueChanged, true);
            app.S1Slider.Value = 5;
            app.S2Slider = uislider(app.GridLayout4);
            app.S2Slider.Limits = [0 10];
            app.S2Slider.ValueChangedFcn = createCallbackFcn(app, @S2SliderValueChanged, true);
            app.S2Slider.Value = 3.5;
            app.S3Slider = uislider(app.GridLayout4);
            app.S3Slider.Limits = [0 10];
            app.S3Slider.ValueChangedFcn = createCallbackFcn(app, @S3SliderValueChanged, true);
            app.S3Slider.Value = 5.5;
            app.S4Slider = uislider(app.GridLayout4);
            app.S4Slider.Limits = [0 10];
            app.S4Slider.ValueChangedFcn = createCallbackFcn(app, @S4SliderValueChanged, true);
            app.S4Slider.Value = 4;
            app.S5Slider = uislider(app.GridLayout4);
            app.S5Slider.Limits = [0 10];
            app.S5Slider.ValueChangedFcn = createCallbackFcn(app, @S5SliderValueChanged, true);
            app.S5Slider.Value = 7;
            app.S6Slider = uislider(app.GridLayout4);
            app.S6Slider.Limits = [0 10];
            app.S6Slider.ValueChangedFcn = createCallbackFcn(app, @S6SliderValueChanged, true);
            app.S6Slider.Value = 6;
            app.x_1Label = uilabel(app.GridLayout4);
            app.x_1Label.Interpreter = 'latex';
            app.x_1Label.Text = '$x_{1}$';
            app.x_2Label = uilabel(app.GridLayout4);
            app.x_2Label.Interpreter = 'latex';
            app.x_2Label.Text = '$x_{2}$';
            app.x_3Label = uilabel(app.GridLayout4);
            app.x_3Label.Interpreter = 'latex';
            app.x_3Label.Text = '$x_{3}$';
            app.x_4Label = uilabel(app.GridLayout4);
            app.x_4Label.Interpreter = 'latex';
            app.x_4Label.Text = '$x_{4}$';
            app.x_5Label = uilabel(app.GridLayout4);
            app.x_5Label.Interpreter = 'latex';
            app.x_5Label.Text = '$x_{5}$';
            app.x_6Label = uilabel(app.GridLayout4);
            app.x_6Label.Interpreter = 'latex';
            app.x_6Label.Text = '$x_{6}$';
            app.MembershipfunctionsettingPanel = uipanel(app.GridLayout2);
            app.MembershipfunctionsettingPanel.Title = 'Membership function setting';
            app.MembershipFunctionLabel = uilabel(app.MembershipfunctionsettingPanel);
            app.MembershipFunctionLabel.Text = 'Membership Function';
            app.MembershipFunctionDropDown = uidropdown(app.MembershipfunctionsettingPanel);
            app.MembershipFunctionDropDown.Items = {'Gaussian', 'Generalized bell-shaped', 'Triangular', 'Pi-shaped', 'Trapezoidal'};
            app.MembershipFunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @MembershipFunctionDropDownValueChanged, true);
            app.MembershipFunctionDropDown.Value = 'Triangular';
            app.MFP1EditField = uieditfield(app.MembershipfunctionsettingPanel, 'numeric');
            app.MFP1EditField.Limits = [0.1 Inf];
            app.MFP1EditField.ValueChangedFcn = createCallbackFcn(app, @MFP1EditFieldValueChanged, true);
            app.MFP1EditField.Value = 2;
            app.A_1Label = uilabel(app.MembershipfunctionsettingPanel);
            app.A_1Label.Interpreter = 'latex';
            app.A_1Label.Text = '$A_{1}$';
            app.MFP2EditField = uieditfield(app.MembershipfunctionsettingPanel, 'numeric');
            app.MFP2EditField.Limits = [0.1 Inf];
            app.MFP2EditField.ValueChangedFcn = createCallbackFcn(app, @MFP2EditFieldValueChanged, true);
            app.MFP2EditField.Value = 2;
            app.A_2Label = uilabel(app.MembershipfunctionsettingPanel);
            app.A_2Label.Interpreter = 'latex';
            app.A_2Label.Text = '$A_{2}$';
            app.MFP3EditField = uieditfield(app.MembershipfunctionsettingPanel, 'numeric');
            app.MFP3EditField.Limits = [0.1 Inf];
            app.MFP3EditField.ValueChangedFcn = createCallbackFcn(app, @MFP3EditFieldValueChanged, true);
            app.MFP3EditField.Value = 2;
            app.A_3Label = uilabel(app.MembershipfunctionsettingPanel);
            app.A_3Label.Interpreter = 'latex';
            app.A_3Label.Text = '$A_{3}$';
            app.ResultsPanel_2 = uipanel(app.GridLayout2);
            app.ResultsPanel_2.Title = 'Results';
            app.mu_1x_nTextAreaLabel = uilabel(app.ResultsPanel_2);
            app.mu_1x_nTextAreaLabel.Interpreter = 'latex';
            app.mu_1x_nTextAreaLabel.Text = '$\mu_{1}(x_{n})=$';
            app.M1TextArea = uitextarea(app.ResultsPanel_2);
            app.M1TextArea.Editable = 'off';
            app.mu_2x_nLabel = uilabel(app.ResultsPanel_2);
            app.mu_2x_nLabel.Interpreter = 'latex';
            app.mu_2x_nLabel.Text = '$\mu_{2}(x_{n})=$';
            app.M2TextArea = uitextarea(app.ResultsPanel_2);
            app.M2TextArea.Editable = 'off';
            app.mu_3x_nLabel = uilabel(app.ResultsPanel_2);
            app.mu_3x_nLabel.Interpreter = 'latex';
            app.mu_3x_nLabel.Text = '$\mu_{3}(x_{n})=$';
            app.M3TextArea = uitextarea(app.ResultsPanel_2);
            app.M3TextArea.Editable = 'off';
            app.mu_4x_nLabel = uilabel(app.ResultsPanel_2);
            app.mu_4x_nLabel.Interpreter = 'latex';
            app.mu_4x_nLabel.Text = '$\mu_{4}(x_{n})=$';
            app.M4TextArea = uitextarea(app.ResultsPanel_2);
            app.M4TextArea.Editable = 'off';
            app.mu_5x_nLabel = uilabel(app.ResultsPanel_2);
            app.mu_5x_nLabel.Interpreter = 'latex';
            app.mu_5x_nLabel.Text = '$\mu_{5}(x_{n})=$';
            app.M5TextArea = uitextarea(app.ResultsPanel_2);
            app.M5TextArea.Editable = 'off';
            app.mu_6x_nLabel = uilabel(app.ResultsPanel_2);
            app.mu_6x_nLabel.Interpreter = 'latex';
            app.mu_6x_nLabel.Text = '$\mu_{6}(x_{n})=$';
            app.M6TextArea = uitextarea(app.ResultsPanel_2);
            app.M6TextArea.Editable = 'off';
            app.SLabel = uilabel(app.ResultsPanel_2);
            app.SLabel.Interpreter = 'latex';
            app.SLabel.Text = '$S = $';
            app.STextArea = uitextarea(app.ResultsPanel_2);
            app.STextArea.Editable = 'off';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.S1Axes = uiaxes(app.GridLayout3);
            ylabel(app.S1Axes, '\mu_{1}(x_{n})')
            zlabel(app.S1Axes, 'Z')
            app.S1Axes.Tag = 'Axes';
            app.S3Axes = uiaxes(app.GridLayout3);
            ylabel(app.S3Axes, '\mu_{3}(x_{n})')
            zlabel(app.S3Axes, 'Z')
            app.S3Axes.Tag = 'Axes';
            app.S5Axes = uiaxes(app.GridLayout3);
            ylabel(app.S5Axes, '\mu_{5}(x_{n})')
            zlabel(app.S5Axes, 'Z')
            app.S5Axes.Tag = 'Axes';
            app.S2Axes = uiaxes(app.GridLayout3);
            ylabel(app.S2Axes, '\mu_{2}(x_{n})')
            zlabel(app.S2Axes, 'Z')
            app.S2Axes.Tag = 'Axes';
            app.S4Axes = uiaxes(app.GridLayout3);
            ylabel(app.S4Axes, '\mu_{4}(x_{n})')
            zlabel(app.S4Axes, 'Z')
            app.S4Axes.Tag = 'Axes';
            app.S6Axes = uiaxes(app.GridLayout3);
            ylabel(app.S6Axes, '\mu_{6}(x_{n})')
            zlabel(app.S6Axes, 'Z')
            app.S6Axes.Tag = 'Axes';
            % Show the figure after all components are created
            app.SoftVotingDemoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = softVotingDemoApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SoftVotingDemoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SoftVotingDemoUIFigure)
        end
    end
end